classdef AudioStreamerTest < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                  matlab.ui.Figure
      runningLamp               matlab.ui.control.Lamp
      runningLampLabel          matlab.ui.control.Label
      DeviceEditField           matlab.ui.control.EditField
      DeviceEditFieldLabel      matlab.ui.control.Label
      SignalEditField           matlab.ui.control.EditField
      SignalEditFieldLabel      matlab.ui.control.Label
      BackgroundEditField       matlab.ui.control.EditField
      BackgroundEditFieldLabel  matlab.ui.control.Label
      WaverStimField            matlab.ui.control.EditField
      PlayButton                matlab.ui.control.Button
      CaregiverStimField        matlab.ui.control.EditField
      DoitButton                matlab.ui.control.Button
      banner                    matlab.ui.control.Image
      ParticipantCheckBox       matlab.ui.control.CheckBox
      WaverCheckBox             matlab.ui.control.CheckBox
      CaregiverCheckBox         matlab.ui.control.CheckBox
      Button                    matlab.ui.control.Button
   end

   
   properties (Access = private)
      streamer % Description
   end
   
   methods (Access = private)
      
      function ShowAudioConfig(app)
         config = app.streamer.GetConfig();

         app.DeviceEditField.Value = char(config.device);
         app.CaregiverStimField.Value = char(config.caregiver);
         app.WaverStimField.Value = char(config.waver);
         app.BackgroundEditField.Value = char(config.background);
         app.SignalEditField.Value = char(config.signal);

         app.CaregiverCheckBox.Enable = true;
         app.WaverCheckBox.Enable = true;
         app.ParticipantCheckBox.Enable = true;
         app.BackgroundEditFieldLabel.Enable = true;
         app.SignalEditFieldLabel.Enable = true;

         app.PlayButton.Enable = true;
      end

      %--------------------------------------------------------------------
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, streamer)
         app.streamer = streamer;

         app.CaregiverCheckBox.Value = true;
         app.WaverCheckBox.Value = true;
         app.ParticipantCheckBox.Value = true;
         
         app.CaregiverCheckBox.Enable = false;
         app.WaverCheckBox.Enable = false;
         app.ParticipantCheckBox.Enable = false;
         app.BackgroundEditFieldLabel.Enable = false;
         app.SignalEditFieldLabel.Enable = false;
         
         app.PlayButton.Enable = false;
         app.DoitButton.Enable = false;

         % Restore figure position
         origin = getpref('AudioStreamerTest', 'Position', []);
         if ~isempty(origin)
            pos = app.UIFigure.Position;
            pos(1:2) = origin;
            app.UIFigure.Position = pos;
         end

         if app.streamer.IsOpen
            app.ShowAudioConfig();
         end

      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         pos = app.UIFigure.Position;
         setpref('AudioStreamerTest', 'Position', pos(1:2));
         delete(app)
      end

      % Button pushed function: DoitButton
      function DoitButtonPushed(app, event)
%             msgbox(['Error writing to Ontrak device: ' ex.message], 'Ontrak Test', 'error');

      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'Audio Test';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.Button = uibutton(app.UIFigure, 'push');
         app.Button.Icon = 'sign_stop.png';
         app.Button.Text = {''; ''};
         app.CaregiverCheckBox = uicheckbox(app.UIFigure);
         app.CaregiverCheckBox.Text = 'Caregiver';
         app.WaverCheckBox = uicheckbox(app.UIFigure);
         app.WaverCheckBox.Text = 'Waver';
         app.ParticipantCheckBox = uicheckbox(app.UIFigure);
         app.ParticipantCheckBox.Text = 'Participant';
         app.banner = uiimage(app.UIFigure);
         app.banner.ImageSource = 'Orange Banner-01.png';
         app.DoitButton = uibutton(app.UIFigure, 'push');
         app.DoitButton.ButtonPushedFcn = createCallbackFcn(app, @DoitButtonPushed, true);
         app.DoitButton.Icon = 'arrow_right_green.png';
         app.DoitButton.Text = 'Do it';
         app.CaregiverStimField = uieditfield(app.UIFigure, 'text');
         app.CaregiverStimField.Editable = 'off';
         app.PlayButton = uibutton(app.UIFigure, 'push');
         app.PlayButton.Icon = 'media_play_green.png';
         app.PlayButton.Text = 'Play';
         app.WaverStimField = uieditfield(app.UIFigure, 'text');
         app.WaverStimField.Editable = 'off';
         app.BackgroundEditFieldLabel = uilabel(app.UIFigure);
         app.BackgroundEditFieldLabel.Text = 'Background';
         app.BackgroundEditField = uieditfield(app.UIFigure, 'text');
         app.BackgroundEditField.Editable = 'off';
         app.SignalEditFieldLabel = uilabel(app.UIFigure);
         app.SignalEditFieldLabel.Text = 'Signal';
         app.SignalEditField = uieditfield(app.UIFigure, 'text');
         app.SignalEditField.Editable = 'off';
         app.DeviceEditFieldLabel = uilabel(app.UIFigure);
         app.DeviceEditFieldLabel.Text = 'Device';
         app.DeviceEditField = uieditfield(app.UIFigure, 'text');
         app.DeviceEditField.Editable = 'off';
         app.runningLampLabel = uilabel(app.UIFigure);
         app.runningLampLabel.Text = 'running';
         app.runningLamp = uilamp(app.UIFigure);
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = AudioStreamerTest(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end