classdef OPP < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure            matlab.ui.Figure
      FileMenu            matlab.ui.container.Menu
      FileMenu_Load       matlab.ui.container.Menu
      FileMenu_Save       matlab.ui.container.Menu
      SettingsMenu        matlab.ui.container.Menu
      ToolsMenu           matlab.ui.container.Menu
      ToysMenu            matlab.ui.container.Menu
      ToysMenu_Test       matlab.ui.container.Menu
      VideoMenu           matlab.ui.container.Menu
      VideoMenu_Test      matlab.ui.container.Menu
      AudioMenu           matlab.ui.container.Menu
      AudioMenu_Test      matlab.ui.container.Menu
      AudioMenu_Settings  matlab.ui.container.Menu
      PhaseTable          matlab.ui.control.Table
      DataFileEditField   matlab.ui.control.EditField
      UITable             matlab.ui.control.Table
      StartButton         matlab.ui.control.Button
      StopButton          matlab.ui.control.Button
      LogPanel            matlab.ui.container.Panel
      LogTextArea         matlab.ui.control.TextArea
      ToolsPanel          matlab.ui.container.Panel
      MixerLamp           matlab.ui.control.Lamp
      MixerLampLabel      matlab.ui.control.Label
      AudioLamp           matlab.ui.control.Lamp
      AudioLampLabel      matlab.ui.control.Label
      VideoLamp           matlab.ui.control.Lamp
      VideoLampLabel      matlab.ui.control.Label
      ToysLamp            matlab.ui.control.Lamp
      ToysLampLabel       matlab.ui.control.Label
   end

   
   properties (Access = public)
      EndFcn = [];
      SynthFcn = [];
      RootFolder = [];
      DataFile = [];
      MeasurementType = '';
      VariableName = '';
      DefaultTrainingValue = NaN;
      DefaultTestValues = [];
   end
   
   properties (Access = private)
      hOntrak = [];
      hStreamer = [];
      hVLC = [];      
      hMixer = [];

      errorColor = [1 0 0];
      successColor = [0 1 0];
      isRunning = false;
      trialIsActive = false;
      
      trialTimer;
      trialStartTime;

      testDescription;
      settings;
      currentPhase; % class
      hardware;

      logText = '';
      settingsAreDirty = false;
      hardwareStatus = struct('ontrak', false, 'vlc', false, 'audio', false, 'mixer', false);
   end
   
   methods (Access = private)
      
      function InitializeDataFile(app)
         app.DataFileEditField.Value = app.DataFile + ".mat";
         info.date = datestr(now);
         save(app.DataFileEditField.Value, 'info');
      end

      %--------------------------------------------------------------------
      function InitializeLog(app)
         app.logText = '';
         app.AddLogEntry('OPP started...');
      end

      %--------------------------------------------------------------------
      function AddLogEntry(app, varargin)
         for k = 1:length(varargin)
            prefix = '';
            if k == 1
               app.logText = [app.logText datestr(now) '   '];
               prefix = [datestr(now, 'HH:MM:SS') '  '];
            end
            app.logText = [app.logText varargin{k} newline];
            app.LogTextArea.Value{end+1} = [prefix varargin{k}];
         end
         app.LogTextArea.scroll('bottom');

         if length(app.logText) > 10000
            app.FlushLog();
         end
      end

      %--------------------------------------------------------------------
      function FlushLog(app)
         if ~isempty(app.logText)
            fp = fopen(app.DataFile + ".log", 'wt');
            fprintf(fp, '%s', app.logText);
            fclose(fp);
            app.logText = '';
         end
      end

      %--------------------------------------------------------------------
      function RestoreSettings(app)
         app.hardware = getpref(mfilename, 'HardwareSettings', app.DefaultHardwareSettings());

         lastSettingsFile = getpref(mfilename, 'LastSettingsFile', '');
         if isempty(lastSettingsFile) || ~exist(lastSettingsFile, 'file')
            app.settings = app.DefaultSettings();
         else
            s = load(lastSettingsFile, 'settings');
            app.settings = s.settings;
         end

         stim = getpref(mfilename, [app.MeasurementType '_Stim'], []);
         if ~isempty(stim)
            app.settings.stimulus.trainingValue = stim.trainingValue;
            app.settings.stimulus.easyValue = stim.easyValue;
            app.settings.stimulus.testValues = stim.testValues;
         end
      end

      %--------------------------------------------------------------------
      function success = InitializeHardware(app)

         d = uiprogressdlg(app.UIFigure, 'Title', 'Please wait', 'Indeterminate', 'on');
         drawnow;

         d.Message = 'Initializing Ontrak...';
         app.hardware.ontrak = app.InitializeOntrak();
         drawnow;

         d.Message = 'Initializing video...';
         app.hardware.vlc = app.InitializeVLC();
         drawnow;

         d.Message = 'Initializing audio streamer...';
         app.hardware.audio = app.InitializeAudioStreamer();
         drawnow;

         d.Message = 'Initializing mixer...';
         app.hardware.mixer = app.InitializeMixer();
         drawnow;

         delete(d);
         
         success = app.hardware.ontrak && app.hardware.vlc && app.hardware.audio && app.hardware.mixer;
      end

      %--------------------------------------------------------------------
      function success = InitializeOntrak(app)
         success = false;

         if ~isempty(app.hOntrak)
            delete(app.hOntrak);
            app.hOntrak = [];
            app.AddLogEntry('Ontrak cleared.');
         end

         if ~app.settings.reward.useToys
            app.ToysLamp.Color = [0 0.25 0];
            success = true;
            return;
         end

         try
            app.hOntrak = Ontrak();
            app.hOntrak = app.hOntrak.Initialize();
            app.hOntrak.TurnOff();
            app.ToysLamp.Color = app.successColor;
            success = true;
            app.AddLogEntry('Ontrak initialized.');
         catch ex
            app.AddLogEntry(['***Failed to initialize Ontrak: ' ex.message]);
            app.ToysLamp.Color = app.errorColor;
         end
      end

      %--------------------------------------------------------------------
      function success = InitializeAudioStreamer(app)
         success = false;
         try
            isLoaded = false;
            asm = System.AppDomain.CurrentDomain.GetAssemblies;
            for k = 1:asm.Length
               isLoaded = startsWith(char(asm(k).FullName), 'OPP.AudioStreamer');
               if isLoaded, break; end
            end

            if ~isLoaded
               NET.addAssembly(fullfile(fileparts(which(mfilename)), 'OPP.AudioStreamer.dll'));
            end

            app.hStreamer = OPP.AudioStreamer;
            app.hStreamer.Initialize(false);

            msg = app.hStreamer.IsOutputDeviceValid(app.hardware.outputDevice);
            msg = char(msg);
            if ~isequal(msg, 'ok')
               error([app.hardware.outputDevice ' - ' msg])
            end

            msg = app.hStreamer.IsMicDeviceValid(app.hardware.micDevice);
            msg = char(msg);
            if ~isequal(msg, 'ok')
               error([app.hardware.micDevice ' - ' msg])
            end

            app.AudioLamp.Color = app.successColor;
            success = true;
            app.AddLogEntry('AudioStreamer initialized.');
         catch ex
            app.AddLogEntry(['***Failed to initialize AudioStreamer: ' ex.message]);
            app.AudioLamp.Color = app.errorColor;
         end
      end

      %--------------------------------------------------------------------
      function success = InitializeVLC(app)
         success = false;
         
         if ~app.settings.reward.useVideo
            if ~isempty(app.hVLC)
               app.hVLC.quit();
               delete(app.hVLC);
               app.hVLC = [];
               app.AddLogEntry('VLC closed.');
            end
            app.VideoLamp.Color = [0 0.25 0];
            success = true;
            return;
         end

         try
            app.hVLC = VLC();
            app.hVLC.Status();
            app.VideoLamp.Color = app.successColor;
            success = true;
            app.AddLogEntry('VLC initialized.');
         catch ex
            app.AddLogEntry('***Failed to initialize VLC', ex.message);
            app.hVLC = [];
            app.VideoLamp.Color = app.errorColor;
         end
      end

      %--------------------------------------------------------------------
      function success = InitializeMixer(app)
         try
            success = false;
            isLoaded = false;
            asm = System.AppDomain.CurrentDomain.GetAssemblies;
            for k = 1:asm.Length
               isLoaded = startsWith(char(asm(k).FullName), 'OPP.Mixer');
               if isLoaded, break; end
            end

            if ~isLoaded
               NET.addAssembly(fullfile(fileparts(which(mfilename)), 'OPP.Mixer.dll'));
            end

            app.hMixer = OPP.Mixer;

            if app.hMixer.IsAvailable(app.hardware.motuAddress)
               app.hMixer.Open();
               app.hMixer.Initialize(app.hardware.motuAddress);
               app.hMixer.MuteAudioStream(true);
               app.hMixer.SetChannelProperty('participant', 'slider enable', false);
               app.hMixer.ConnectMonitor(-1);
               app.MixerLamp.Color = app.successColor;
               success = true;
               app.AddLogEntry('Mixer initialized.');
            else
               app.AddLogEntry(['***Failed to initialize Mixer: device not found at address ' app.hardware.motuAddress]);
               app.MixerLamp.Color = app.errorColor;
            end
         catch
            app.AddLogEntry('***Failed to initialize Mixer', ex.message);
            app.MixerLamp.Color = app.errorColor;
         end
      end

      %--------------------------------------------------------------------
      function InitializePhases(app)
         nrows = length(app.settings.stimulus.testValues) + 1;
         data = cell(nrows, 3);
         rowNames = cell(nrows, 1);
         for k = 1:nrows
            rowNames{k} = num2str(k);
            if k == 1
               data{k, 1} = 'Training';
               data{k, 2} = app.settings.stimulus.trainingValue;
            elseif k == 2
               data{k, 1} = 'Easy';
               data{k, 2} = app.settings.stimulus.easyValue;
            else
               data{k, 1} = 'Test';
               data{k, 2} = app.settings.stimulus.testValues(k-2);
            end            
         end

         buttonStyle = uistyle("BackgroundColor", 0.94 * [ 1 1 1], "HorizontalAlignment", 'center');
         buttonCells = [1:3; 3 3 3]';
         for k = 1:3
            data{k, 3} = 'Edit';
            addStyle(app.PhaseTable, buttonStyle, "cell", buttonCells)
         end

         app.PhaseTable.CellSelectionCallback = @app.PhaseTableCellSelection;
         app.PhaseTable.Data = data;
         app.PhaseTable.RowName = rowNames;

         app.PhaseTable.Selection = [];
         app.HighlightPhaseTableRow(1);
      end

      %--------------------------------------------------------------------
      function SetAudioStream(app)
         if isempty(app.SynthFcn), return; end
         
         stim = app.SynthFcn();
         
         zeroSignal = zeros(48000, 1);

         app.hStreamer.SetConfig('Out 1-24 (2- MOTU Pro Audio)', 'Microphone (Polsen US-OB-33)', 48000);
         app.hStreamer.SetNumReps(3);

         y = zeroSignal;
         y(1:length(stim.caregiver.waveform)) = stim.caregiver.waveform;
         app.hStreamer.SetSignal('caregiver', stim.caregiver.name, y);

         y = zeroSignal;
         y(1:length(stim.waver.waveform)) = stim.waver.waveform;
         app.hStreamer.SetSignal('waver', stim.waver.name, y);

         y = zeroSignal;
         y(1:length(stim.background.waveform)) = stim.background.waveform;
         app.hStreamer.SetSignal('background', stim.background.name, y);

         y = zeroSignal;
         y(1:length(stim.signal.waveform)) = stim.signal.waveform;
         app.hStreamer.SetSignal('signal', stim.signal.name, y);
      end
      
      %--------------------------------------------------------------------
      function StartTrial(app)
         app.trialIsActive = true;
         app.hStreamer.Trigger();
         
         while ~app.hStreamer.IsTriggered()
            pause(0.1);
         end

         app.UIFigure.Color = 'r';
         drawnow;
         app.trialTimer.StartDelay = 5;
         app.trialTimer.start();
      end

      %--------------------------------------------------------------------
      function TrialTimerCallback(app, ~, ~)
         app.trialIsActive = false;
         app.UIFigure.Color = 0.94 * [1 1 1];
      end
      
      %--------------------------------------------------------------------
      function H = DefaultHardwareSettings(~)
         H.motuAddress = '169.254.221.196';
         H.outputDevice = 'Out 1-24 (2- MOTU Pro Audio)';
         H.micDevice = 'Microphone (Polsen US-OB-33)';
         H.caregiverCalFile = '';
         H.waverCalFile = '';
         H.participantCalFile = '';
      end

      %--------------------------------------------------------------------
      function S = DefaultSettings(app)
         S.stimulus.repsPerTrial = 3;
         S.stimulus.ITI_s = 1;
         S.stimulus.trialDuration_s = 4;
         S.stimulus.trainingValue = app.DefaultTrainingValue;
         S.stimulus.easyValue = app.DefaultTrainingValue;
         S.stimulus.testValues = app.DefaultTestValues;

         S.reward.useToys = true;
         S.reward.minToyTime_s = 3;
         S.reward.useVideo = true;
         S.reward.minVideoTime_s = 5;
         S.reward.videoPlaylist = {'',''};

         S.phaseDefs = cell(3, 1);

         S.phaseDefs{1} = app.DefaultPhaseDefinition();
         S.phaseDefs{1}.name = 'Training';
         S.phaseDefs{1}.numSignal = 8;
         S.phaseDefs{1}.numNoSignal = 2;
         S.phaseDefs{1}.rewardRequiresResponse = false;
         
         S.phaseDefs{2} = app.DefaultPhaseDefinition();
         S.phaseDefs{2}.name = 'Easy';
         S.phaseDefs{2}.bounceBack.ifMaxNumTrials = false;
         S.phaseDefs{2}.boundBack.consecutiveMissType = 'none';
         
         S.phaseDefs{3} = app.DefaultPhaseDefinition();
         S.phaseDefs{3}.name = 'Easy';
      end
      
      %--------------------------------------------------------------------
      function P = DefaultPhaseDefinition(app)
         P.name = '';
         P.stimulusValue = NaN;
         P.numSignal = 10;
         P.numNoSignal = 10;
         P.rewardRequiresResponse = true;

         P.passIf.numSignalCorrect = 4;
         P.passIf.outOfNumSignal = 5;
         P.passIf.numNonSignalCorrect = 4;
         P.passIf.outOfNumNonSignal = 5;
         P.passIf.maxNumTrials = 20;

         P.passBounceBackIf = P.passIf;

         P.bounceBack.ifMaxNumTrials = true;
         P.bounceBack.ifNumConsecutiveMiss = 3;
         P.bounceBack.consecutiveMissType = 'signals';
      end

      %--------------------------------------------------------------------
      function HardwareSettingsChanged(app, settings)
         app.hardware = settings;
         setpref(mfilename, 'HardwareSettings', app.hardware);
         app.AddLogEntry('Hardware settings changed');
      end
      
      %--------------------------------------------------------------------
      function PhaseTableCellSelection(app, ~, ~)
         rowNum = app.PhaseTable.Selection(1);
         app.HighlightPhaseTableRow(rowNum);

         if rowNum < 4 && app.PhaseTable.Selection(2) == 3
            disp('EDIT!!!');
         end
      end

      %--------------------------------------------------------------------
      function HighlightPhaseTableRow(app, rowNum)
         highlightStyle = uistyle("BackgroundColor", "#F48B74", "FontColor", 'w', 'FontWeight', 'bold');
         plainStyle = uistyle("BackgroundColor", [1 1 1], "FontColor", 'k', 'FontWeight', 'normal');

         nrows = size(app.PhaseTable.Data, 1);
         for k = 1:nrows
            if k < 4
               addStyle(app.PhaseTable, plainStyle, "cell", [k k; 1 2]')
            else
               addStyle(app.PhaseTable, plainStyle, "row", k);
            end
         end

         if rowNum < 4
            addStyle(app.PhaseTable, highlightStyle, "cell", [rowNum rowNum; 1 2]');
         else
            addStyle(app.PhaseTable, highlightStyle, "row", rowNum);
         end
      end
      
      %--------------------------------------------------------------------
      function SettingsChanged(app, settings, stimChanged)
         reinitOntrak = app.settings.reward.useToys ~= settings.reward.useToys;
         reinitVLC = app.settings.reward.useVideo ~= settings.reward.useVideo;

         app.settings = settings;
         app.settingsAreDirty = true;
         app.AddLogEntry('Settings changed');

         if stimChanged
            stim = struct(...
               'trainingValue', app.settings.stimulus.trainingValue, ...
               'easyValue', app.settings.stimulus.easyValue, ...
               'testValues', app.settings.stimulus.testValues ...
               );
            setpref(mfilename, [app.MeasurementType '_Stim'], stim);
            app.InitializePhases();
         end
 
         if reinitOntrak
            app.hardwareStatus.ontrak = app.InitializeOntrak();
         end

         if reinitVLC
            app.hardwareStatus.vlc = app.InitializeVLC();
         end

         app.StartButton.Enable = app.hardware.ontrak && app.hardware.vlc && app.hardware.audio && app.hardware.mixer;
      end
      
      %--------------------------------------------------------------------
      function saved = SaveSettings(app)
         saved = false;
         filePath = getpref(mfilename, 'LastSettingsFile', '');
         if isempty(filePath)
            filePath = fullfile(app.RootFolder, 'Settings Files');
         end

         [f, p] = uiputfile('*.opp_set.mat', 'Save settings', filePath);
         if ischar(f)
            filePath = fullfile(p, f);
            settings = app.settings; %#ok<ADPROP> 
            save(filePath, 'settings');
            saved = true;
            app.settingsAreDirty = false;
            setpref(mfilename, 'LastSettingsFile', filePath);
         end
      end

      %--------------------------------------------------------------------
      function LoadSettings(app)
         filePath = getpref(mfilename, 'LastSettingsFile', fullfile(app.RootFolder, 'Settings Files'));
         [f, p] = uigetfile('*.opp_set.mat', 'Load settings', filePath);
         if ischar(f)
            filePath = fullfile(p, f);
            s = load(filePath, 'settings');
            
            reinitOntrak = app.settings.reward.useToys ~= s.settings.reward.useToys;
            reinitVLC = app.settings.reward.useVideo ~= s.settings.reward.useVideo;

            app.settings = s.settings;
            app.settingsAreDirty = false;
            setpref(mfilename, 'LastSettingsFile', filePath);

            if reinitOntrak
               app.hardwareStatus.ontrak = app.InitializeOntrak();
            end

            if reinitVLC
               app.hardwareStatus.vlc = app.InitializeVLC();
            end
         end

         stim = getpref(mfilename, [app.MeasurementType '_Stim'], []);
         if ~isempty(stim)
            app.settings.stimulus.trainingValue = stim.trainingValue;
            app.settings.stimulus.easyValue = stim.easyValue;
            app.settings.stimulus.testValues = stim.testValues;
         end
      end
end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, varargin)
         % Restore figure position
         origin = getpref('OPP', 'Position', []);
         if ~isempty(origin)
            pos = app.UIFigure.Position;
            pos(1:2) = origin;
            app.UIFigure.Position = pos;
         end         

         % Process input arguments
         for k = 1:2:length(varargin)
            app.(varargin{k}) = varargin{k+1};
         end
         app.DataFile = string(app.DataFile);

         app.StopButton.Visible = 'off';
         app.StartButton.Enable = false;

         % Initialize timers
         app.trialTimer = timer(...
            'TimerFcn', @app.TrialTimerCallback ...
            );

         app.InitializeDataFile();
         app.InitializeLog();

         app.RestoreSettings();
         app.InitializePhases();

         hardwareReady = app.InitializeHardware();

         if hardwareReady
            app.StartButton.Enable = true;
            app.StopButton.Visible = 'on';
         end

%          app.SetAudioStream();
%          app.hStreamer.Start();
      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)

         if app.settingsAreDirty
            resp = questdlg('Settings have been changed. Save them now?', 'OPP', 'Yes', 'No', 'Cancel', 'Cancel');
            if isequal(resp, 'Cancel')
               return;
            elseif isequal(resp, 'Yes')
               saved = app.SaveSettings();
               if ~saved, return; end
            end
         end
         
         uiresume(app.UIFigure);
         pos = app.UIFigure.Position;
         setpref('OPP', 'Position', pos(1:2));

         try
            if ~isempty(app.hStreamer)
               app.AddLogEntry('stopping AudioStreamer...');
               app.hStreamer.Stop();
               app.AddLogEntry('closing AudioStreamer...');
               app.hStreamer.Close();
            end

            if ~isempty(app.hOntrak)
               app.AddLogEntry('closing Ontrak...');
               delete(app.hOntrak);
            end

            if ~isempty(app.hVLC)
               app.AddLogEntry('closing VLC...');
               app.hVLC.quit();
            end

            if ~isempty(app.hMixer)
               app.AddLogEntry('closing Mixer...');
               app.hMixer.Close();
            end

            if ~isempty(app.EndFcn)
               app.EndFcn();
            end
         catch ex
            app.AddLogEntry(['Error closing OPP: ' ex.message]);
         end

         app.AddLogEntry('...OPP finished.');
         app.FlushLog();
         
         delete(app)
      end

      % Menu selected function: FileMenu_Save
      function FileMenu_SaveSelected(app, event)
         app.SaveSettings();
      end

      % Menu selected function: FileMenu_Load
      function FileMenu_LoadSelected(app, event)
         app.LoadSettings();
      end

      % Menu selected function: SettingsMenu
      function SettingsMenuSelected(app, event)
         OPPSettings(app.settings, app.UIFigure.Position, fullfile(app.RootFolder, 'Video Files'), @app.SettingsChanged);
      end

      % Menu selected function: ToysMenu_Test
      function ToysMenu_TestSelected(app, event)
         h = OntrakTest(app.hOntrak);
         uiwait(h.UIFigure);
      end

      % Menu selected function: AudioMenu_Test
      function AudioMenu_TestSelected(app, event)
         h = AudioStreamerTest(app.hStreamer, app.hMixer);
         uiwait(h.UIFigure);
      end

      % Menu selected function: AudioMenu_Settings
      function AudioMenu_SettingsSelected(app, event)
         OPPHardwareSettings(app.UIFigure.Position, app.hardware, app.hStreamer, app.hMixer, fullfile(app.RootFolder, 'Calibration Files'), @app.HardwareSettingsChanged);
      end

      % Menu selected function: VideoMenu_Test
      function VideoMenu_TestSelected(app, event)
         if ~isempty(app.hVLC)
            h = VLCTest(app.hVLC);
            uiwait(h.UIFigure);         
         end
      end

      % Button pushed function: StartButton
      function StartButtonPushed(app, event)
         app.isRunning = true;
         app.StartButton.Visible = false;
         app.hMixer.MuteAudioStream(false);         
      end

      % Button pushed function: StopButton
      function StopButtonPushed(app, event)
         app.isRunning = false;
         app.StartButton.Visible = true;
         app.hMixer.MuteAudioStream(true);
      end

      % Window key press function: UIFigure
      function UIFigureWindowKeyPress(app, event)
         key = event.Key;
%          if isequal(key, 'space')
%             if ~app.trialIsActive
%                app.StartTrial();
%             end
%          end
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'OPP';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
         app.FileMenu = uimenu(app.UIFigure);
         app.FileMenu.Text = 'File';
         app.FileMenu_Load = uimenu(app.FileMenu);
         app.FileMenu_Load.MenuSelectedFcn = createCallbackFcn(app, @FileMenu_LoadSelected, true);
         app.FileMenu_Load.Text = 'Load settings';
         app.FileMenu_Save = uimenu(app.FileMenu);
         app.FileMenu_Save.MenuSelectedFcn = createCallbackFcn(app, @FileMenu_SaveSelected, true);
         app.FileMenu_Save.Text = 'Save settings';
         app.SettingsMenu = uimenu(app.UIFigure);
         app.SettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenuSelected, true);
         app.SettingsMenu.Text = 'Settings';
         app.ToolsMenu = uimenu(app.UIFigure);
         app.ToolsMenu.Text = 'Tools';
         app.ToysMenu = uimenu(app.ToolsMenu);
         app.ToysMenu.Text = 'Toys';
         app.ToysMenu_Test = uimenu(app.ToysMenu);
         app.ToysMenu_Test.MenuSelectedFcn = createCallbackFcn(app, @ToysMenu_TestSelected, true);
         app.ToysMenu_Test.Text = 'Test';
         app.VideoMenu = uimenu(app.ToolsMenu);
         app.VideoMenu.Text = 'Video';
         app.VideoMenu_Test = uimenu(app.VideoMenu);
         app.VideoMenu_Test.MenuSelectedFcn = createCallbackFcn(app, @VideoMenu_TestSelected, true);
         app.VideoMenu_Test.Text = 'Test';
         app.AudioMenu = uimenu(app.ToolsMenu);
         app.AudioMenu.Text = 'Audio';
         app.AudioMenu_Test = uimenu(app.AudioMenu);
         app.AudioMenu_Test.MenuSelectedFcn = createCallbackFcn(app, @AudioMenu_TestSelected, true);
         app.AudioMenu_Test.Text = 'Test';
         app.AudioMenu_Settings = uimenu(app.AudioMenu);
         app.AudioMenu_Settings.MenuSelectedFcn = createCallbackFcn(app, @AudioMenu_SettingsSelected, true);
         app.AudioMenu_Settings.Text = 'Settings';
         app.ToolsPanel = uipanel(app.UIFigure);
         app.ToolsPanel.Title = 'Tools';
         app.ToysLampLabel = uilabel(app.ToolsPanel);
         app.ToysLampLabel.Text = 'Toys';
         app.ToysLamp = uilamp(app.ToolsPanel);
         app.VideoLampLabel = uilabel(app.ToolsPanel);
         app.VideoLampLabel.Text = 'Video';
         app.VideoLamp = uilamp(app.ToolsPanel);
         app.AudioLampLabel = uilabel(app.ToolsPanel);
         app.AudioLampLabel.Text = 'Audio';
         app.AudioLamp = uilamp(app.ToolsPanel);
         app.MixerLampLabel = uilabel(app.ToolsPanel);
         app.MixerLampLabel.Text = 'Mixer';
         app.MixerLamp = uilamp(app.ToolsPanel);
         app.LogPanel = uipanel(app.UIFigure);
         app.LogPanel.Title = 'Log';
         app.LogTextArea = uitextarea(app.LogPanel);
         app.LogTextArea.Editable = 'off';
         app.StopButton = uibutton(app.UIFigure, 'push');
         app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
         app.StopButton.Icon = 'sign_stop.png';
         app.StopButton.Text = {''; ''};
         app.StartButton = uibutton(app.UIFigure, 'push');
         app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
         app.StartButton.Icon = 'media_play_green.png';
         app.StartButton.Text = 'Start';
         app.UITable = uitable(app.UIFigure);
         app.UITable.ColumnName = {'Total'; 'Last N'};
         app.UITable.RowName = {'Trials'; 'Hits'; 'False alarms'};
         app.UITable.SelectionType = 'row';
         app.UITable.Multiselect = 'off';
         app.DataFileEditField = uieditfield(app.UIFigure, 'text');
         app.DataFileEditField.Editable = 'off';
         app.PhaseTable = uitable(app.UIFigure);
         app.PhaseTable.ColumnName = {'Phase'; 'Value'; ''};
         app.PhaseTable.RowName = {};
         app.PhaseTable.Multiselect = 'off';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = OPP(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end