classdef OPPHardwareSettings < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                   matlab.ui.Figure
      WaverMicDeviceDropDown     matlab.ui.control.DropDown
      MicrophoneLabel_2          matlab.ui.control.Label
      MicDeviceDropDown          matlab.ui.control.DropDown
      MicrophoneLabel            matlab.ui.control.Label
      OutputDeviceDropDown       matlab.ui.control.DropDown
      OutputdeviceDropDownLabel  matlab.ui.control.Label
      MOTUStatusEditField        matlab.ui.control.EditField
      MOTUaddressEditField       matlab.ui.control.EditField
      MOTUaddressEditFieldLabel  matlab.ui.control.Label
      CancelButton               matlab.ui.control.Button
      OKButton                   matlab.ui.control.Button
      banner                     matlab.ui.control.Image
   end

   
   properties (Access = private)
      Settings % Description
      ChangeFcn;
      result = 'cancel';
      hstreamer
      hmixer
   end
   
   methods (Access = private)
      
      function TryMOTU(app)
         app.MOTUStatusEditField.Value = 'trying...';
         drawnow;

         isAvailable = app.hmixer.IsAvailable(app.MOTUaddressEditField.Value);
         if isAvailable
            app.MOTUStatusEditField.Value = 'connected';
         else
            app.MOTUStatusEditField.Value = 'device not found';
         end
      end

   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, centerOn, settings, streamer, mixer, changeFcn)
         app.Settings = settings;
         app.hstreamer = streamer;
         app.hmixer = mixer;
         app.ChangeFcn = changeFcn;

         pos = app.UIFigure.Position;
         pos(1) = centerOn(1) + centerOn(3)/2 - pos(3)/2;
         pos(2) = centerOn(2) + centerOn(4)/2 - pos(4)/2;
         app.UIFigure.Position = pos;

         app.MOTUaddressEditField.Value = app.Settings.motuAddress;

         value = app.hstreamer.EnumerateOutputDevices();
         dev = cell(value.Length, 1);
         for k = 1:value.Length
            dev{k} = char(value(k));
         end
         app.OutputDeviceDropDown.Items = dev;
         if ismember(dev, app.Settings.outputDevice)
            app.OutputDeviceDropDown.Value = app.Settings.outputDevice;
         end

         value = app.hstreamer.EnumerateMicrophones();
         dev = cell(value.Length, 1);
         for k = 1:value.Length
            dev{k} = char(value(k));
         end
         app.MicDeviceDropDown.Items = dev;
         if ismember(app.Settings.micDevice, dev)
            app.MicDeviceDropDown.Value = app.Settings.micDevice;
         end

         app.WaverMicDeviceDropDown.Items = dev;
         if isfield(app.Settings, 'waverMicDevice') && ismember(app.Settings.waverMicDevice, dev)
            app.WaverMicDeviceDropDown.Value = app.Settings.waverMicDevice;
         end

         drawnow;
         app.TryMOTU();
      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         if isequal(app.result, 'ok') && ~isempty(app.ChangeFcn)
            app.ChangeFcn(app.Settings);
         end
         uiresume(app.UIFigure);
         delete(app)
      end

      % Button pushed function: OKButton
      function OKButtonPushed(app, event)
         app.result = 'ok';
         close(app.UIFigure);         
      end

      % Button pushed function: CancelButton
      function CancelButtonPushed(app, event)
         app.result = 'cancel';
         close(app.UIFigure);
      end

      % Value changed function: MOTUaddressEditField
      function MOTUaddressEditFieldValueChanged(app, event)
         app.Settings.motuAddress = app.MOTUaddressEditField.Value;
         app.TryMOTU();         
      end

      % Callback function
      function CBrowseButtonPushed(app, event)
         app.BrowseForCalFile('Caregiver');
      end

      % Callback function
      function WBrowseButtonPushed(app, event)
         app.BrowseForCalFile('Waver');         
      end

      % Callback function
      function PBrowseButtonPushed(app, event)
         app.BrowseForCalFile('Participant');                  
      end

      % Value changed function: OutputDeviceDropDown
      function OutputDeviceDropDownValueChanged(app, event)
         app.Settings.outputDevice = app.OutputDeviceDropDown.Value;
      end

      % Value changed function: MicDeviceDropDown
      function MicDeviceDropDownValueChanged(app, event)
         app.Settings.micDevice = app.MicDeviceDropDown.Value;         
      end

      % Value changed function: WaverMicDeviceDropDown
      function WaverMicDeviceDropDownValueChanged(app, event)
         app.Settings.waverMicDevice = app.WaverMicDeviceDropDown.Value;         
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'Hardware settings';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.banner = uiimage(app.UIFigure);
         app.banner.ImageSource = 'Orange Banner-01.png';
         app.OKButton = uibutton(app.UIFigure, 'push');
         app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
         app.OKButton.Text = 'OK';
         app.CancelButton = uibutton(app.UIFigure, 'push');
         app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
         app.CancelButton.Text = 'Cancel';
         app.MOTUaddressEditFieldLabel = uilabel(app.UIFigure);
         app.MOTUaddressEditFieldLabel.Text = 'MOTU address';
         app.MOTUaddressEditField = uieditfield(app.UIFigure, 'text');
         app.MOTUaddressEditField.ValueChangedFcn = createCallbackFcn(app, @MOTUaddressEditFieldValueChanged, true);
         app.MOTUStatusEditField = uieditfield(app.UIFigure, 'text');
         app.MOTUStatusEditField.Editable = 'off';
         app.OutputdeviceDropDownLabel = uilabel(app.UIFigure);
         app.OutputdeviceDropDownLabel.Text = 'Output device';
         app.OutputDeviceDropDown = uidropdown(app.UIFigure);
         app.OutputDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputDeviceDropDownValueChanged, true);
         app.MicrophoneLabel = uilabel(app.UIFigure);
         app.MicrophoneLabel.Text = 'Tester-to-Waver Microphone';
         app.MicDeviceDropDown = uidropdown(app.UIFigure);
         app.MicDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @MicDeviceDropDownValueChanged, true);
         app.MicrophoneLabel_2 = uilabel(app.UIFigure);
         app.MicrophoneLabel_2.Text = 'Waver-to-Tester Microphone';
         app.WaverMicDeviceDropDown = uidropdown(app.UIFigure);
         app.WaverMicDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @WaverMicDeviceDropDownValueChanged, true);
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = OPPHardwareSettings(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end