classdef OPP_AcousticCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MeasureButton                matlab.ui.control.Button
        MeterButton                  matlab.ui.control.Button
        StopMeasureButton            matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        ExitButton                   matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        PlayButton                   matlab.ui.control.Button
        AttenEditField               matlab.ui.control.NumericEditField
        AttenuationdBEditFieldLabel  matlab.ui.control.Label
        StopButton                   matlab.ui.control.Button
    end

   
   properties (Access = private)
      calPath % Description
      attenuation % Description
      isDirty % Description
      freq % Description
      hstreamer
      hmixer
      soundMeter = [];
      stopMeasurement
   end
   
   methods (Access = private)
      
      function SaveData(app)
         oldSPL = app.UITable.Data.Old;         
         newSPL = app.UITable.Data.New;
         spl = NaN(size(newSPL));
         for k = 1:length(newSPL)
            if isempty(newSPL{k})
               spl(k) = str2double(oldSPL(k,:));
            else
               spl(k) = str2double(newSPL{k});
            end
         end
         opp_cal.FreqHz = app.freq;
         opp_cal.SPL_Vrms = spl;
         save(app.calPath, 'opp_cal', '-append');

         app.isDirty = false;
      end

      %--------------------------------------------------------------------
      function AcquireData(app)
         ifreq = app.UITable.Selection;
         if isempty(ifreq)
            return;
         end

         app.stopMeasurement = false;
         app.PlayButton.Enable = false;
         app.StopMeasureButton.Enable = true;
         app.MeasureButton.Visible = false;

         Fs = 48000;
         T = 30;
         npts = round(Fs * T);
         t = (0:npts-1) / Fs;

         app.hstreamer.Stop();
         n = 0;
         while n < 50
            status = char(app.hstreamer.GetStatus());
            if isequal(status, 'stopped')
               break;
            end
            pause(0.1);
         end

         try
            for kf = 1:length(ifreq)

               app.UITable.Selection = ifreq(kf);
               f = str2double(app.UITable.Data.Freq(ifreq(kf),:));

               y = sin(2*pi*t*f);
               y = sqrt(2) * y * 10 ^ (app.attenuation / 20);

               app.hstreamer.SetSignal('caregiver', 'null', zeros(size(y)));
               app.hstreamer.SetSignal('waver', 'null', zeros(size(y)));
               app.hstreamer.SetSignal('background', 'tone', y);
               app.hstreamer.SetSignal('signal', 'null', zeros(size(y)));

               app.hstreamer.Start();
               app.hmixer.MuteAudioStream(false);

               value = app.soundMeter.AcquireValue();
               app.UITable.Data.Measured(ifreq(kf)) = {num2str(value, '%.1f')};
               app.UITable.Data.New(ifreq(kf)) = {num2str(value - app.attenuation, '%.1f')};
               app.isDirty = true;

               app.hstreamer.Stop();
               n = 0;
               while n < 50
                  status = char(app.hstreamer.GetStatus());
                  if isequal(status, 'stopped')
                     break;
                  end
                  pause(0.1);
               end

               if app.stopMeasurement
                  break;
               end
            end
         catch ex
            errordlg(ex.message);
         end

         app.PlayButton.Enable = true;
         app.MeasureButton.Visible = true;
         app.UITable.Enable = 'on';
      end
   end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, freq, calPath, streamer, mixer)
         app.freq = freq(:);
         app.calPath = calPath;
         app.hstreamer = streamer;
         app.hmixer = mixer;

         % Restore attenuation
         app.attenuation = getsharedpref('APTC_AcousticCalibration', 'Attenuation', -20);
         app.AttenEditField.Value = app.attenuation;

         % Restore figure position
         pos = app.UIFigure.Position;
         monitorPos = get(0, 'MonitorPositions');
         iprimary = find(monitorPos(:, 1) == 1);
         x = 0.5*(monitorPos(iprimary, 3) - pos(3));
         y = 0.5*(monitorPos(iprimary, 4) - pos(4));
%          rmpref('APTC_AcousticCalibration', 'Position');
         origin = getsharedpref('APTC_AcousticCalibration', 'Position', [x y pos(3:4)]);
         if ~isempty(origin)
            pos(1:2) = origin(1:2);
            app.UIFigure.Position = pos;
         end

         app.isDirty = false;
         load(calPath, 'opp_cal');
         if exist('opp_cal', 'var')
            cal = opp_cal;
         else
            cal.FreqHz = app.freq;
            cal.SPL_Vrms = NaN*ones(size(app.freq));
         end

         Lmax = interp1(cal.FreqHz, cal.SPL_Vrms, app.freq);

         app.UITable.RowName = {};
         d = table(num2str(app.freq, '%d'), num2str(Lmax, '%.1f'), cell(size(app.freq)), cell(size(app.freq)), 'VariableNames', {'Freq', 'Old', 'New', 'Measured'});
         app.UITable.Data = d;       
         app.UITable.addStyle(uistyle('HorizontalAlignment', 'right'), 'table', '');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
         if app.isDirty
            response = questdlg('You have unsaved changes to the calibration.', '', 'Save', 'Don''t save', 'Cancel', 'Save');
            if isequal(response, 'Cancel')
               return;
            elseif isequal(response, 'Save')
               app.SaveData();
            end
         end
         
         if ~isempty(app.soundMeter)
             app.soundMeter.Quit();
         end

         pos = app.UIFigure.Position;
         setsharedpref('APTC_AcousticCalibration', 'Position', pos(1:2));
         setsharedpref('APTC_AcousticCalibration', 'Attenuation', app.attenuation);

         uiresume(app.UIFigure);

         delete(app)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
         close(app.UIFigure);
        end

        % Value changed function: AttenEditField
        function AttenEditFieldValueChanged(app, event)
         app.attenuation = app.AttenEditField.Value;         
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
         ifreq = app.UITable.Selection;
         if isempty(ifreq)
            return;
         end
         f = str2double(app.UITable.Data.Freq(ifreq,:));

         Fs = 48000;
         T = 5;
         npts = round(Fs * T);
         t = (0:npts-1) / Fs;
         y = sin(2*pi*t*f);
         y = sqrt(2) * y * 10 ^ (app.attenuation / 20);

         app.hstreamer.Stop();
         n = 0;
         while n < 50
            status = char(app.hstreamer.GetStatus());
            if isequal(status, 'stopped')
               break;
            end
            pause(0.1);
         end
         
         app.hstreamer.SetSignal('caregiver', 'null', zeros(size(y)));
         app.hstreamer.SetSignal('waver', 'null', zeros(size(y)));
         app.hstreamer.SetSignal('background', 'tone', y);
         app.hstreamer.SetSignal('signal', 'null', zeros(size(y)));

         app.UITable.Enable = 'off';

         app.PlayButton.Visible = false;
         app.hstreamer.Start();
         app.hmixer.MuteAudioStream(false);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
         app.hmixer.MuteAudioStream(true);
         app.hstreamer.Stop();
         app.UITable.Enable = 'on';
         app.PlayButton.Visible = true;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
         indices = event.Indices;
         newData = event.NewData;
         newSPL = newData - app.attenuation;
         app.UITable.Data.New(indices(1)) = {newSPL};
         app.isDirty = true;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
         app.SaveData();
        end

        % Button pushed function: MeterButton
        function MeterButtonPushed(app, event)
         app.soundMeter = Sound_Meter();
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
         app.AcquireData();
        end

        % Button pushed function: StopMeasureButton
        function StopMeasureButtonPushed(app, event)
         app.StopMeasureButton.Enable = false;
         app.stopMeasurement = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Acoustic Calibration';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.AttenuationdBEditFieldLabel = uilabel(app.UIFigure);
            app.AttenuationdBEditFieldLabel.Text = 'Attenuation (dB)';
            app.AttenEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttenEditField.Limits = [-Inf -3];
            app.AttenEditField.ValueChangedFcn = createCallbackFcn(app, @AttenEditFieldValueChanged, true);
            app.AttenEditField.Value = -3;
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Panel = uipanel(app.UIFigure);
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Freq'; 'Old SPL'; 'New SPL'; 'Measured'};
            app.UITable.RowName = {''; ''};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = [false false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.StopMeasureButton = uibutton(app.UIFigure, 'push');
            app.StopMeasureButton.ButtonPushedFcn = createCallbackFcn(app, @StopMeasureButtonPushed, true);
            app.StopMeasureButton.Text = 'Stop';
            app.MeterButton = uibutton(app.UIFigure, 'push');
            app.MeterButton.ButtonPushedFcn = createCallbackFcn(app, @MeterButtonPushed, true);
            app.MeterButton.Text = 'Meter';
            app.MeasureButton = uibutton(app.UIFigure, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure selected';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OPP_AcousticCalibration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end