classdef VLCTest < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure    matlab.ui.Figure
      vlcImage    matlab.ui.control.Image
      banner      matlab.ui.control.Image
      PlayButton  matlab.ui.control.Button
      StopButton  matlab.ui.control.Button
   end

   
   properties (Access = private)
      hVLC
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, vlc_handle)
         app.hVLC = vlc_handle;

         % Restore figure position
         origin = getpref('VLCTest', 'Position', []);
         if ~isempty(origin)
            pos = app.UIFigure.Position;
            pos(1:2) = origin;
            app.UIFigure.Position = pos;
         end
      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         uiresume(app.UIFigure);

         pos = app.UIFigure.Position;
         setpref('VLCTest', 'Position', pos(1:2));
         delete(app)         
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'VLC Test';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.StopButton = uibutton(app.UIFigure, 'push');
         app.StopButton.Icon = 'sign_stop.png';
         app.StopButton.Text = {''; ''};
         app.PlayButton = uibutton(app.UIFigure, 'push');
         app.PlayButton.Icon = 'media_play_green.png';
         app.PlayButton.Text = 'Play';
         app.banner = uiimage(app.UIFigure);
         app.banner.ImageSource = 'Orange Banner-01.png';
         app.vlcImage = uiimage(app.UIFigure);
         app.vlcImage.ImageSource = 'VLC.png';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = VLCTest(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end